classdef IndefProgDialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PleaseWaitUIFigure  matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        lblMessage          matlab.ui.control.Label
    end

    
    properties (Access = private)
        mainMessage = 'Starting'; % Actual message to display
        dotCount = 0; % Current number of dots rendered
        maxDots = 10; % Maximum number of dots
        timeDotMillis = 300; %Milliseconds between dots

        timerFuture = [];
        bkgPaused = false;
        bkgStopRequest = false;
        bkgPauseRequest = false;
    end
    
    methods (Access = public)
        
        function app = updateMessage(app, newMessage)
            app.mainMessage = newMessage;

            if app.timeDotMillis >= 1000
                %Otherwise probably faster to just wait for next update
                app.pauseBkg();
                app = app.renderUpdate();
                app.unpauseBkg();
            end
        end

        function boolRes = timerActive(app)
            boolRes = false;
            if isempty(app.timerFuture); return; end
            boolRes = ~strcmp(app.timerFuture.State, 'running');
        end

        function app = closeMe(app)
            app.stopBkg();
            delete(app);
        end
    end
    
    methods (Access = private)

        function app = pauseBkg(app)
            if ~app.timerActive(); return; end
            app.bkgPauseRequest = true;
            waitfor(app, 'bkgPaused', 'true');
        end

        function app = unpauseBkg(app)
            if ~app.timerActive(); return; end
            app.bkgPauseRequest = false;
            waitfor(app, 'bkgPaused', 'false');
        end

        function app = stopBkg(app)
            if ~app.timerActive(); return; end
            app.bkgStopRequest = true;
            waitfor(app.timerFuture, 'State', 'finished');

        end

        function app = startBkg(app)
            if app.timerActive(); return; end
            app.bkgPaused = false;
            app.bkgStopRequest = false;

            %app.timerFuture = parfeval(backgroundPool,@IndefProgDialog.bkgLoop,1,app);
            app.timerFuture = parfeval(backgroundPool,@app.bkgLoop,1);
        end

        function app = bkgLoop(app)
            while(true)
                %Perform checks
                app.bkgPaused = app.bkgPauseRequest;
                waitfor(app, 'bkgPauseRequest', false);
                app.bkgPaused = false;

                if app.bkgStopRequest
                    return;
                end

                %Update
                app = app.onTick();

                %Wait for millis
                pause(app.timeDotMillis ./ 1000);
            end
        end

        function app = onTick(app)
            app.dotCount = app.dotCount + 1;
            if (app.dotCount > app.maxDots)
                app.dotCount = 0;
            end
            app = app.renderUpdate();
        end
        
        function app = renderUpdate(app)
            if app.dotCount > 0
                app.lblMessage.Value = [app.mainMessage repmat('.', 1, app.dotCount)];
            else
                app.lblMessage.Value = app.mainMessage;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MaxDots, IntervalMillis)
            app.maxDots = MaxDots;
            app.timeDotMillis = IntervalMillis;
            app.startBkg();
        end

        % Close request function: PleaseWaitUIFigure
        function PleaseWaitUIFigureCloseRequest(app, event)
            %Kill background thread.
            app.stopBkg();
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PleaseWaitUIFigure = uifigure('Visible', 'off');
            app.PleaseWaitUIFigure.Name = 'Please Wait';
            app.PleaseWaitUIFigure.CloseRequestFcn = createCallbackFcn(app, @PleaseWaitUIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.PleaseWaitUIFigure);
            app.lblMessage = uilabel(app.GridLayout);
            app.lblMessage.Tag = 'lblMessage';
            app.lblMessage.Text = 'Starting';
            % Show the figure after all components are created
            app.PleaseWaitUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IndefProgDialog(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PleaseWaitUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PleaseWaitUIFigure)
        end
    end
end