classdef TrueSpotMinimal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TrueSpotLiteUIFigure      matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        Panel_2                   matlab.ui.container.Panel
        GridLayout3               matlab.ui.container.GridLayout
        RunButton                 matlab.ui.control.Button
        Panel_3                   matlab.ui.container.Panel
        GridLayout2               matlab.ui.container.GridLayout
        BrowseButton              matlab.ui.control.Button
        SpecXMLEditField          matlab.ui.control.EditField
        SpecXMLEditFieldLabel     matlab.ui.control.Label
        Panel                     matlab.ui.container.Panel
        GridLayout4               matlab.ui.container.GridLayout
        QuantFitCheckBox          matlab.ui.control.CheckBox
        SpotDetectionCheckBox     matlab.ui.control.CheckBox
        CellSegmentationCheckBox  matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        lastBrowsePath = '.'; % Path used to seed file browser
        settingsPath = []; % Path to settings file
    end
    
    methods (Access = private)
        
        function executeRun(app, xmlPath, listener)
            funcTag = 'TrueSpotMinimal.executeRun';
            GUIUtils.sendMessage(listener, funcTag, 'Reading spec XML...');
            batchList = TrueSpotXML.readSettingsXML(xmlPath);

            batchCount = size(batchList, 2);
            for i = 1:batchCount
                GUIUtils.sendMessage(listener, funcTag, sprintf('Working on batch %d of %d...', i, batchCount));
                myBatch = batchList(i);

                %Open Log
                if ~isempty(listener)
                    logPath = [myBatch.batchSettings.paths.outputPath filesep 'TSLiteRunLog.log'];
                    listener = listener.openLog(logPath);
                end

                %Initialize batch
                myBatch.batchSettings = myBatch.batchSettings.initializeForRun();

                if app.CellSegmentationCheckBox.Value
                    myBatch.batchSettings = myBatch.batchSettings.runCellSeg(listener);
                end
                if app.SpotDetectionCheckBox.Value
                    myBatch.batchSettings = myBatch.batchSettings.runSpotDetect(myBatch.channels, listener);
                end
                if app.QuantFitCheckBox.Value
                    myBatch.batchSettings = myBatch.batchSettings.runQuant(myBatch.channels, listener);
                end

                %Close Log
                if ~isempty(listener)
                    listener = listener.closeLog();
                end
            end
            GUIUtils.sendMessage(listener, funcTag, 'Done!');
        end
        
        function app = disableAll(app)
            app.RunButton.Enable = false;
            app.BrowseButton.Enable = false;
            app.SpecXMLEditField.Enable = false;
            app.QuantFitCheckBox.Enable = false;
            app.SpotDetectionCheckBox.Enable = false;
            app.CellSegmentationCheckBox.Enable = false;
        end

        function app = enableAll(app)
            app.RunButton.Enable = true;
            app.BrowseButton.Enable = true;
            app.SpecXMLEditField.Enable = true;
            app.QuantFitCheckBox.Enable = true;
            app.SpotDetectionCheckBox.Enable = true;
            app.CellSegmentationCheckBox.Enable = true;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Make sure wd is correct and load saved options
            myPath = mfilename('fullpath');
            [myDir, ~, ~] = fileparts(myPath);
            [myDir, ~, ~] = fileparts(myDir);

            addpath(myDir);
            addpath([myDir filesep 'core']);
            addpath([myDir filesep 'thirdparty']);
            addpath([myDir filesep 'gui']);
            addpath([myDir filesep 'settings']);
            addpath([myDir filesep 'plots']);
            cd(myDir); %???

            settingsDir = [myDir filesep 'settings'];
            if ~isfolder(settingsDir)
                mkdir(settingsDir);
            end
            app.settingsPath = [settingsDir filesep 'minguistg.mat'];
            if isfile(app.settingsPath)
                load(app.settingsPath, 'paths');
                app.lastBrowsePath = paths.lastBrowsePath;
                clear paths
            end

        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [file,location] = uigetfile(...
                {'*.xml', 'eXtensible Markup Language'}, ...
                'Select Specification XML', ...
                app.lastBrowsePath);
            if ~isequal(file,0)
                mypath = fullfile(location,file);
                app.SpecXMLEditField.Value = mypath;
                app.lastBrowsePath = mypath;
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            xmlPath = app.SpecXMLEditField.Value;
            if ~isfile(xmlPath)
                msgbox(["Provided XML path " xmlPath " could not be found!"],"Invalid XML","error");
                return;
            end

            %Spawn a dialog box that listens to the run state
            %Call executeRun in the background and have it close the dialog
            %   box when it is done.
            %https://www.mathworks.com/help/matlab/matlab_prog/use-the-background-to-make-your-apps-more-responsive.html
            listener = GUIMessageListener;
            
            %Synchronous (For testing because APPARENTLY UI components
            %can't be modified asynchrously FOR SOME REASON)
            app.disableAll();
            waitfor(app.RunButton, 'Enable', false);
            try
                app.executeRun(xmlPath, listener); 
            catch MEx
                %TODO Also notify GUI
                disp(MEx.getReport('extended'));
            end
            app.enableAll();

            % waitDialog = IndefProgDialog(10, 300);
            % listener.dialogHandle = waitDialog;
            % f = parfeval(backgroundPool,@app.executeRun,1,xmlPath,listener);
            % waitfor(f, 'State', 'finished');
            % %https://www.mathworks.com/matlabcentral/answers/53160-close-a-message-box-thru-coding
            % if exist('waitDialog', 'var')
            %     waitDialog.closeMe();
            % end
        end

        % Close request function: TrueSpotLiteUIFigure
        function TrueSpotLiteUIFigureCloseRequest(app, event)
            paths = struct();
            paths.lastBrowsePath = app.lastBrowsePath;
            save(app.settingsPath, 'paths');
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TrueSpotLiteUIFigure = uifigure('Visible', 'off');
            app.TrueSpotLiteUIFigure.Name = 'TrueSpot Lite';
            app.TrueSpotLiteUIFigure.CloseRequestFcn = createCallbackFcn(app, @TrueSpotLiteUIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.TrueSpotLiteUIFigure);
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.Panel);
            app.CellSegmentationCheckBox = uicheckbox(app.GridLayout4);
            app.CellSegmentationCheckBox.Text = 'Cell Segmentation';
            app.CellSegmentationCheckBox.Value = true;
            app.SpotDetectionCheckBox = uicheckbox(app.GridLayout4);
            app.SpotDetectionCheckBox.Text = 'Spot Detection';
            app.SpotDetectionCheckBox.Value = true;
            app.QuantFitCheckBox = uicheckbox(app.GridLayout4);
            app.QuantFitCheckBox.Text = 'Quant/Fit';
            app.QuantFitCheckBox.Value = true;
            app.Panel_3 = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.Panel_3);
            app.SpecXMLEditFieldLabel = uilabel(app.GridLayout2);
            app.SpecXMLEditFieldLabel.Text = 'Spec XML:';
            app.SpecXMLEditField = uieditfield(app.GridLayout2, 'text');
            app.BrowseButton = uibutton(app.GridLayout2, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse...';
            app.Panel_2 = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.Panel_2);
            app.RunButton = uibutton(app.GridLayout3, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            % Show the figure after all components are created
            app.TrueSpotLiteUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TrueSpotMinimal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TrueSpotLiteUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TrueSpotLiteUIFigure)
        end
    end
end