classdef D_ChannelHist < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout      matlab.ui.container.GridLayout
        glyoButtons     matlab.ui.container.GridLayout
        btnApply        matlab.ui.control.Button
        btnCancel       matlab.ui.control.Button
        sldIntensity    matlab.ui.control.RangeSlider
        IntensityLabel  matlab.ui.control.Label
        pnlButtons      matlab.ui.container.Panel
        histoAxes       matlab.ui.control.UIAxes
    end

    % Events with associated public callbacks
    events (HasCallbackProperty, NotifyAccess = private)
        CloseRequested % When apply or cancel is pushed
    end

    properties (Access = private)
        closeSelection (1,1) logical = false;
        histoX double
        histoY double
    end
    

    methods (Access = public)
        
        function val = getSelectedMin(comp)
            val = comp.sldIntensity.Value(1);
        end

        function val = getSelectedMax(comp)
            val = comp.sldIntensity.Value(2);
        end
        
        function loadImageHistogram(comp, rawImage)
            binSize = 1;
            minVal = 0;
            if isa(rawImage, 'uint8')
                maxVal = 255;
            elseif isa(rawImage, 'uint16')
                maxVal = 65535;
            else
                minVal = min(rawImage, [], 'all', 'omitnan');
                maxVal = min(rawImage, [], 'all', 'omitnan');
                binSize = NaN;
            end

            if ~isnan(binSize)
                binCount = maxVal + 1;
                edges = minVal:binSize:(maxVal+1);
                [comp.histoY, edges] = histcounts(rawImage, edges);
                comp.histoX = edges(1:binCount);
            else
                [comp.histoY, edges] = histcounts(rawImage);
                binCount = size(edges, 2) - 1;
                comp.histoX = edges(1:binCount);
                binSize = comp.histoX(2) - comp.histoX(1);
            end

            %Update slider
            comp.sldIntensity.Limits = [minVal maxVal];
            comp.sldIntensity.Step = binSize;

            %Update histogram figure
            comp.updateHistoAxes();
        end
        
        function closeSelection = getCloseSelection(comp)
            closeSelection = comp.closeSelection;
        end
    end
    
    methods (Access = private)
        
        function updateHistoAxes(comp)
            axes(comp.histoAxes);
            cla;
            plot(comp.histoAxes, comp.histoX, comp.histoY);
            hold on;
            rmin = comp.getSelectedMin();
            if ~isnan(rmin)
                xline(rmin);
            end
            rmax = comp.getSelectedMax();
            if ~isnan(rmax)
                xline(rmax);
            end
            
            ylabel(comp.histoAxes, '');
            xlabel(comp.histoAxes, 'Intensity');
            set(comp.histoAxes,'YTickLabel',[]);
            title(comp.histoAxes, 'Channel Histogram');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: sldIntensity
        function sldIntensityValueChanged(comp, event)
            %value = comp.sldIntensity.Value;
            comp.updateHistoAxes();
        end

        % Button pushed function: btnApply
        function btnApplyButtonPushed(comp, event)
            comp.closeSelection = true;
            notify(comp, 'CloseRequested');
        end

        % Button pushed function: btnCancel
        function btnCancelButtonPushed(comp, event)
            comp.closeSelection = false;
            notify(comp, 'CloseRequested');
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a property is changed
        function update(comp)
            % Use this function to update the underlying components
            comp.updateHistoAxes();
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 539 398];
            comp.GridLayout = uigridlayout(comp);
            comp.histoAxes = uiaxes(comp.GridLayout);
            title(comp.histoAxes, 'Title')
            xlabel(comp.histoAxes, 'X')
            ylabel(comp.histoAxes, 'Y')
            zlabel(comp.histoAxes, 'Z')
            comp.pnlButtons = uipanel(comp.GridLayout);
            comp.IntensityLabel = uilabel(comp.GridLayout);
            comp.IntensityLabel.Text = 'Intensity';
            comp.sldIntensity = uislider(comp.GridLayout, 'range');
            comp.sldIntensity.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @sldIntensityValueChanged, true);
            comp.glyoButtons = uigridlayout(comp.GridLayout);
            comp.btnCancel = uibutton(comp.glyoButtons, 'push');
            comp.btnCancel.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @btnCancelButtonPushed, true);
            comp.btnCancel.Text = 'Cancel';
            comp.btnApply = uibutton(comp.glyoButtons, 'push');
            comp.btnApply.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @btnApplyButtonPushed, true);
            comp.btnApply.Text = 'Apply';
        end
    end
end