classdef D_ChannelSettings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ChannelSettingsUIFigure         matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        Panel                           matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        OkayButton                      matlab.ui.control.Button
        CancelButton                    matlab.ui.control.Button
        MiscOptionsPanel                matlab.ui.container.Panel
        GridLayout6                     matlab.ui.container.GridLayout
        OutputlocalmaximacallstocsvCheckBox  matlab.ui.control.CheckBox
        OutputparameterstotextfilesCheckBox  matlab.ui.control.CheckBox
        SpotDetectWorkersEditField      matlab.ui.control.NumericEditField
        SpotDetectWorkersEditFieldLabel  matlab.ui.control.Label
        ParametersPanel                 matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        ThresholdSettingsButton         matlab.ui.control.Button
        Panel_2                         matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        QuantcellzeroCheckBox           matlab.ui.control.CheckBox
        ClouddetectionCheckBox          matlab.ui.control.CheckBox
        RefilterforquantCheckBox        matlab.ui.control.CheckBox
        DeadpixelremovalCheckBox        matlab.ui.control.CheckBox
        ZXYRatioEditField               matlab.ui.control.NumericEditField
        ZXYRatioEditFieldLabel          matlab.ui.control.Label
        ZEditField                      matlab.ui.control.NumericEditField
        ZEditFieldLabel                 matlab.ui.control.Label
        XYEditField                     matlab.ui.control.NumericEditField
        XYEditFieldLabel                matlab.ui.control.Label
        QuantFitRadiusLabel             matlab.ui.control.Label
        ControlChannelIDEditField       matlab.ui.control.NumericEditField
        ControlChannelIDEditFieldLabel  matlab.ui.control.Label
        cbControl                       matlab.ui.control.CheckBox
        txtZMax                         matlab.ui.control.NumericEditField
        MaxEditField_2Label             matlab.ui.control.Label
        txtZMin                         matlab.ui.control.NumericEditField
        MinEditField_2Label             matlab.ui.control.Label
        cbZTrim                         matlab.ui.control.CheckBox
        ZTrimLabel                      matlab.ui.control.Label
        cbAutoThRange                   matlab.ui.control.CheckBox
        txtMaxTh                        matlab.ui.control.NumericEditField
        MaxEditFieldLabel               matlab.ui.control.Label
        ThresholdScanRangeLabel         matlab.ui.control.Label
        txtMinTh                        matlab.ui.control.NumericEditField
        MinEditFieldLabel               matlab.ui.control.Label
        GaussianRadiusEditField         matlab.ui.control.NumericEditField
        GaussianRadiusEditFieldLabel    matlab.ui.control.Label
        ChannelIDEditField              matlab.ui.control.NumericEditField
        ChannelIDEditFieldLabel         matlab.ui.control.Label
        MetadataPanel                   matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        TargetTypeEditField             matlab.ui.control.EditField
        TargetTypeEditFieldLabel        matlab.ui.control.Label
        ProbeNameEditField              matlab.ui.control.EditField
        ProbeNameEditFieldLabel         matlab.ui.control.Label
        TargetNameEditField             matlab.ui.control.EditField
        TargetNameEditFieldLabel        matlab.ui.control.Label
        zEditField                      matlab.ui.control.NumericEditField
        zEditFieldLabel                 matlab.ui.control.Label
        yEditField                      matlab.ui.control.NumericEditField
        yEditFieldLabel                 matlab.ui.control.Label
        xEditField                      matlab.ui.control.NumericEditField
        xEditFieldLabel                 matlab.ui.control.Label
        PointSizenmLabel                matlab.ui.control.Label
    end

    
    properties (Access = public)
        exitSelection = false; % Description
    end
    
    methods (Access = public)
        
        function loadSettingsToForm(app, channelInfo)
            if isempty(channelInfo); return; end
            app.TargetNameEditField.Value = channelInfo.metadata.targetName;
            app.ProbeNameEditField.Value = channelInfo.metadata.probeName;
            app.TargetTypeEditField.Value = channelInfo.metadata.targetMolType;

            app.xEditField.Value = channelInfo.metadata.pointSize.x;
            app.yEditField.Value = channelInfo.metadata.pointSize.y;
            app.zEditField.Value = channelInfo.metadata.pointSize.z;

            app.ChannelIDEditField.Value = channelInfo.channelIndex;
            app.GaussianRadiusEditField.Value = channelInfo.spotCountSettings.gaussRad;
            app.ZXYRatioEditField.Value = channelInfo.spotCountSettings.zAdj;
            app.XYEditField.Value = channelInfo.spotCountSettings.fitRadXY;
            app.ZEditField.Value = channelInfo.spotCountSettings.fitRadZ;
            app.DeadpixelremovalCheckBox.Value = channelInfo.spotCountSettings.useDPC;
            app.RefilterforquantCheckBox.Value = ~channelInfo.spotCountSettings.quantNoRefilter;
            app.ClouddetectionCheckBox.Value = ~channelInfo.spotCountSettings.quantNoClouds;
            app.QuantcellzeroCheckBox.Value = channelInfo.spotCountSettings.quantCellZero;

            app.SpotDetectWorkersEditField.Value = channelInfo.spotCountSettings.spotDetectThreads;
            app.OutputparameterstotextfilesCheckBox.Value = channelInfo.options.runparamtxt;
            app.OutputlocalmaximacallstocsvCheckBox.Value = channelInfo.options.csvrange;

            if channelInfo.spotCountSettings.controlImageChannel > 0
                app.cbControl.Value = true;
                app.ControlChannelIDEditField.Value = channelInfo.spotCountSettings.controlImageChannel;
            else
                app.cbControl.Value = false; %TODO Callback trigger?
            end

            if (channelInfo.spotCountSettings.zMin > 0) | (channelInfo.spotCountSettings.zMax > 0)
                app.cbZTrim.Value = true;
                app.txtZMin.Value = channelInfo.spotCountSettings.zMin;
                app.txtZMax.Value = channelInfo.spotCountSettings.zMax;
            else
                app.cbZTrim.Value = false; %TODO Callback trigger?
            end

            if (channelInfo.thresholdSettings.thMin > 0) | (channelInfo.thresholdSettings.thMax > 0)
                app.cbAutoThRange.Value = false;
                app.txtMinTh.Value = channelInfo.thresholdSettings.thMin;
                app.txtMaxTh.Value = channelInfo.thresholdSettings.thMax;
            else
                app.cbAutoThRange.Value = true; %TODO Callback trigger?
            end
        end

        function channelInfo = updateSettingsFromForm(app, channelInfo)
            %TODO
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, channelInfo)
            app.loadSettingsToForm(channelInfo);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.exitSelection = false;
            delete(app);
        end

        % Button pushed function: OkayButton
        function OkayButtonPushed(app, event)
            app.exitSelection = true;
            delete(app);
        end

        % Value changed function: cbAutoThRange
        function cbAutoThRangeValueChanged(app, event)
            value = app.cbAutoThRange.Value;
            app.txtMinTh.Enable = ~value;
            app.txtMinTh.Editable = ~value;
            app.MinEditFieldLabel.Enable = ~value;
            app.txtMaxTh.Enable = ~value;
            app.txtMaxTh.Editable = ~value;
            app.MaxEditFieldLabel.Enable = ~value;
        end

        % Value changed function: cbControl
        function cbControlValueChanged(app, event)
            value = app.cbControl.Value;
            app.ControlChannelIDEditField.Enable = value;
            app.ControlChannelIDEditField.Editable = value;
            app.ControlChannelIDEditFieldLabel.Enable = value;
        end

        % Value changed function: cbZTrim
        function cbZTrimValueChanged(app, event)
            value = app.cbZTrim.Value;
            app.txtZMin.Enable = value;
            app.txtZMin.Editable = value;
            app.MinEditField_2Label.Enable = value;
            app.txtZMax.Enable = value;
            app.txtZMax.Editable = value;
            app.MaxEditField_2Label.Enable = value;
        end

        % Button pushed function: ThresholdSettingsButton
        function ThresholdSettingsButtonPushed(app, event)
            %TODO!
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ChannelSettingsUIFigure = uifigure('Visible', 'off');
            app.ChannelSettingsUIFigure.Name = 'Channel Settings';
            app.GridLayout = uigridlayout(app.ChannelSettingsUIFigure);
            app.MetadataPanel = uipanel(app.GridLayout);
            app.MetadataPanel.Title = 'Metadata';
            app.GridLayout3 = uigridlayout(app.MetadataPanel);
            app.PointSizenmLabel = uilabel(app.GridLayout3);
            app.PointSizenmLabel.Text = 'Point Size (nm)';
            app.xEditFieldLabel = uilabel(app.GridLayout3);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.GridLayout3, 'numeric');
            app.xEditField.Limits = [0 Inf];
            app.yEditFieldLabel = uilabel(app.GridLayout3);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.GridLayout3, 'numeric');
            app.yEditField.Limits = [0 Inf];
            app.zEditFieldLabel = uilabel(app.GridLayout3);
            app.zEditFieldLabel.Text = 'z';
            app.zEditField = uieditfield(app.GridLayout3, 'numeric');
            app.zEditField.Limits = [0 Inf];
            app.TargetNameEditFieldLabel = uilabel(app.GridLayout3);
            app.TargetNameEditFieldLabel.Text = 'Target Name';
            app.TargetNameEditField = uieditfield(app.GridLayout3, 'text');
            app.TargetNameEditField.Placeholder = '<Empty>';
            app.ProbeNameEditFieldLabel = uilabel(app.GridLayout3);
            app.ProbeNameEditFieldLabel.Text = 'Probe Name';
            app.ProbeNameEditField = uieditfield(app.GridLayout3, 'text');
            app.ProbeNameEditField.Placeholder = '<Empty>';
            app.TargetTypeEditFieldLabel = uilabel(app.GridLayout3);
            app.TargetTypeEditFieldLabel.Text = 'Target Type';
            app.TargetTypeEditField = uieditfield(app.GridLayout3, 'text');
            app.TargetTypeEditField.Placeholder = '<Empty>';
            app.ParametersPanel = uipanel(app.GridLayout);
            app.ParametersPanel.Title = 'Parameters';
            app.GridLayout4 = uigridlayout(app.ParametersPanel);
            app.ChannelIDEditFieldLabel = uilabel(app.GridLayout4);
            app.ChannelIDEditFieldLabel.Text = 'Channel ID';
            app.ChannelIDEditField = uieditfield(app.GridLayout4, 'numeric');
            app.ChannelIDEditField.Limits = [1 Inf];
            app.ChannelIDEditField.Value = 1;
            app.GaussianRadiusEditFieldLabel = uilabel(app.GridLayout4);
            app.GaussianRadiusEditFieldLabel.Text = 'Gaussian Radius';
            app.GaussianRadiusEditField = uieditfield(app.GridLayout4, 'numeric');
            app.GaussianRadiusEditField.Limits = [0 Inf];
            app.GaussianRadiusEditField.Value = 7;
            app.MinEditFieldLabel = uilabel(app.GridLayout4);
            app.MinEditFieldLabel.Enable = 'off';
            app.MinEditFieldLabel.Text = 'Min';
            app.txtMinTh = uieditfield(app.GridLayout4, 'numeric');
            app.txtMinTh.Limits = [0 Inf];
            app.txtMinTh.Editable = 'off';
            app.txtMinTh.Enable = 'off';
            app.txtMinTh.Value = 10;
            app.ThresholdScanRangeLabel = uilabel(app.GridLayout4);
            app.ThresholdScanRangeLabel.Text = 'Threshold Scan Range';
            app.MaxEditFieldLabel = uilabel(app.GridLayout4);
            app.MaxEditFieldLabel.Enable = 'off';
            app.MaxEditFieldLabel.Text = 'Max';
            app.txtMaxTh = uieditfield(app.GridLayout4, 'numeric');
            app.txtMaxTh.Limits = [0 Inf];
            app.txtMaxTh.Editable = 'off';
            app.txtMaxTh.Enable = 'off';
            app.txtMaxTh.Value = 500;
            app.cbAutoThRange = uicheckbox(app.GridLayout4);
            app.cbAutoThRange.ValueChangedFcn = createCallbackFcn(app, @cbAutoThRangeValueChanged, true);
            app.cbAutoThRange.Text = 'Auto Threshold Range';
            app.cbAutoThRange.Value = true;
            app.ZTrimLabel = uilabel(app.GridLayout4);
            app.ZTrimLabel.Text = 'Z Trim';
            app.cbZTrim = uicheckbox(app.GridLayout4);
            app.cbZTrim.ValueChangedFcn = createCallbackFcn(app, @cbZTrimValueChanged, true);
            app.cbZTrim.Text = 'Apply Z Trim';
            app.MinEditField_2Label = uilabel(app.GridLayout4);
            app.MinEditField_2Label.Enable = 'off';
            app.MinEditField_2Label.Text = 'Min';
            app.txtZMin = uieditfield(app.GridLayout4, 'numeric');
            app.txtZMin.Limits = [1 Inf];
            app.txtZMin.Editable = 'off';
            app.txtZMin.Enable = 'off';
            app.txtZMin.Value = 1;
            app.MaxEditField_2Label = uilabel(app.GridLayout4);
            app.MaxEditField_2Label.Enable = 'off';
            app.MaxEditField_2Label.Text = 'Max';
            app.txtZMax = uieditfield(app.GridLayout4, 'numeric');
            app.txtZMax.Limits = [1 Inf];
            app.txtZMax.Editable = 'off';
            app.txtZMax.Enable = 'off';
            app.txtZMax.Value = 1;
            app.cbControl = uicheckbox(app.GridLayout4);
            app.cbControl.ValueChangedFcn = createCallbackFcn(app, @cbControlValueChanged, true);
            app.cbControl.Text = 'Use Control';
            app.ControlChannelIDEditFieldLabel = uilabel(app.GridLayout4);
            app.ControlChannelIDEditFieldLabel.Enable = 'off';
            app.ControlChannelIDEditFieldLabel.Text = 'Control Channel ID';
            app.ControlChannelIDEditField = uieditfield(app.GridLayout4, 'numeric');
            app.ControlChannelIDEditField.Limits = [1 Inf];
            app.ControlChannelIDEditField.Editable = 'off';
            app.ControlChannelIDEditField.Enable = 'off';
            app.ControlChannelIDEditField.Value = 1;
            app.QuantFitRadiusLabel = uilabel(app.GridLayout4);
            app.QuantFitRadiusLabel.Text = 'Quant Fit Radius';
            app.XYEditFieldLabel = uilabel(app.GridLayout4);
            app.XYEditFieldLabel.Text = 'XY';
            app.XYEditField = uieditfield(app.GridLayout4, 'numeric');
            app.XYEditField.Limits = [0 Inf];
            app.XYEditField.Value = 4;
            app.ZEditFieldLabel = uilabel(app.GridLayout4);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.GridLayout4, 'numeric');
            app.ZEditField.Limits = [0 Inf];
            app.ZEditField.Value = 2;
            app.ZXYRatioEditFieldLabel = uilabel(app.GridLayout4);
            app.ZXYRatioEditFieldLabel.Text = 'Z/XY Ratio';
            app.ZXYRatioEditField = uieditfield(app.GridLayout4, 'numeric');
            app.ZXYRatioEditField.Limits = [0 Inf];
            app.ZXYRatioEditField.Value = 1;
            app.Panel_2 = uipanel(app.GridLayout4);
            app.GridLayout5 = uigridlayout(app.Panel_2);
            app.DeadpixelremovalCheckBox = uicheckbox(app.GridLayout5);
            app.DeadpixelremovalCheckBox.Text = 'Dead pixel removal';
            app.DeadpixelremovalCheckBox.Value = true;
            app.RefilterforquantCheckBox = uicheckbox(app.GridLayout5);
            app.RefilterforquantCheckBox.Text = 'Refilter for quant';
            app.ClouddetectionCheckBox = uicheckbox(app.GridLayout5);
            app.ClouddetectionCheckBox.Enable = 'off';
            app.ClouddetectionCheckBox.Text = 'Cloud detection';
            app.QuantcellzeroCheckBox = uicheckbox(app.GridLayout5);
            app.QuantcellzeroCheckBox.Text = 'Quant "cell zero"';
            app.QuantcellzeroCheckBox.Value = true;
            app.ThresholdSettingsButton = uibutton(app.GridLayout4, 'push');
            app.ThresholdSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @ThresholdSettingsButtonPushed, true);
            app.ThresholdSettingsButton.Text = 'Threshold Settings...';
            app.MiscOptionsPanel = uipanel(app.GridLayout);
            app.MiscOptionsPanel.Title = 'Misc. Options';
            app.GridLayout6 = uigridlayout(app.MiscOptionsPanel);
            app.SpotDetectWorkersEditFieldLabel = uilabel(app.GridLayout6);
            app.SpotDetectWorkersEditFieldLabel.Text = 'Spot Detect Workers';
            app.SpotDetectWorkersEditField = uieditfield(app.GridLayout6, 'numeric');
            app.SpotDetectWorkersEditField.Limits = [1 Inf];
            app.SpotDetectWorkersEditField.Value = 1;
            app.OutputparameterstotextfilesCheckBox = uicheckbox(app.GridLayout6);
            app.OutputparameterstotextfilesCheckBox.Text = 'Output parameters to text files';
            app.OutputlocalmaximacallstocsvCheckBox = uicheckbox(app.GridLayout6);
            app.OutputlocalmaximacallstocsvCheckBox.Text = 'Output local maxima calls to csv';
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.CancelButton = uibutton(app.GridLayout2, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.OkayButton = uibutton(app.GridLayout2, 'push');
            app.OkayButton.ButtonPushedFcn = createCallbackFcn(app, @OkayButtonPushed, true);
            app.OkayButton.Text = 'Okay';
            % Show the figure after all components are created
            app.ChannelSettingsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = D_ChannelSettings(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ChannelSettingsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ChannelSettingsUIFigure)
        end
    end
end