classdef D_ProjectMetadata < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AdditionalMetadataUIFigure  matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        CellTissueEditField         matlab.ui.control.EditField
        CellTissueEditFieldLabel    matlab.ui.control.Label
        SpeciesEditField            matlab.ui.control.EditField
        SpeciesEditFieldLabel       matlab.ui.control.Label
        Panel                       matlab.ui.container.Panel
        GridLayout2                 matlab.ui.container.GridLayout
        OkayButton                  matlab.ui.control.Button
        CancelButton                matlab.ui.control.Button
        zEditField                  matlab.ui.control.NumericEditField
        zEditFieldLabel             matlab.ui.control.Label
        yEditField                  matlab.ui.control.NumericEditField
        yEditFieldLabel             matlab.ui.control.Label
        xEditField                  matlab.ui.control.NumericEditField
        xEditFieldLabel             matlab.ui.control.Label
        VoxelPixelDimsnmLabel       matlab.ui.control.Label
        AdditionalMetadataLabel     matlab.ui.control.Label
    end

    
    properties (Access = private)
        %For holding values after deletion
        speciesString;
        cellTypeString;
        voxel_x;
        voxel_y;
        voxel_z;

        is_open = false;
    end
    
    properties (Access = public)
        exitSelection = false; % Description
    end
    
    methods (Access = public)
        
        function loadProjectInfoToForm(app, project)
            if ~isempty(project)
                app.xEditField.Value = project.metadata.voxelSize.x;
                app.yEditField.Value = project.metadata.voxelSize.y;
                app.zEditField.Value = project.metadata.voxelSize.z;
                if ~isempty(project.metadata.species)
                    app.SpeciesEditField.Value = project.metadata.species;
                else
                    app.SpeciesEditField.Value = '<Empty>';
                end
                if ~isempty(project.metadata.cellType)
                    app.CellTissueEditField.Value = project.metadata.cellType;
                else
                    app.CellTissueEditField.Value = '<Empty>';
                end
            else
                app.SpeciesEditField.Value = '<Empty>';
                app.CellTissueEditField.Value = '<Empty>';
                app.xEditField.Value = 0;
                app.yEditField.Value = 0;
                app.zEditField.Value = 0;
            end
        end

        function project = updateProjectInfoFromForm(app, project)
            if isempty(project); return; end
            if app.is_open
                app.updateInternalFields();
            end
            project.metadata.voxelSize.x = app.voxel_x;
            project.metadata.voxelSize.y = app.voxel_y;
            project.metadata.voxelSize.z = app.voxel_z;
            project.metadata.species = app.speciesString;
            project.metadata.cellType = app.cellTypeString;
        end
    end
    
    methods (Access = private)
        
        function updateInternalFields(app)
            app.voxel_x = app.xEditField.Value;
            app.voxel_y = app.yEditField.Value;
            app.voxel_z = app.zEditField.Value;
            if ~strcmp(app.SpeciesEditField.Value, '<Empty>')
                app.speciesString = app.SpeciesEditField.Value;
            else
                app.speciesString = [];
            end
            if ~strcmp(app.CellTissueEditField.Value, '<Empty>')
                app.cellTypeString = app.CellTissueEditField.Value;
            else
                app.cellTypeString = [];
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, proj)
            app.is_open = true;
            app.loadProjectInfoToForm(proj);
        end

        % Button pushed function: OkayButton
        function OkayButtonPushed(app, event)
            app.updateInternalFields();
            app.exitSelection = true;
            app.is_open = false;
            delete(app);
        end

        % Close request function: AdditionalMetadataUIFigure
        function AdditionalMetadataUIFigureCloseRequest(app, event)
            app.is_open = false;
            delete(app);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.exitSelection = false;
            app.is_open = false;
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AdditionalMetadataUIFigure = uifigure('Visible', 'off');
            app.AdditionalMetadataUIFigure.Name = 'Additional Metadata';
            app.AdditionalMetadataUIFigure.CloseRequestFcn = createCallbackFcn(app, @AdditionalMetadataUIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.AdditionalMetadataUIFigure);
            app.AdditionalMetadataLabel = uilabel(app.GridLayout);
            app.AdditionalMetadataLabel.Text = 'Additional Metadata';
            app.VoxelPixelDimsnmLabel = uilabel(app.GridLayout);
            app.VoxelPixelDimsnmLabel.Text = 'Voxel/Pixel Dims (nm)';
            app.xEditFieldLabel = uilabel(app.GridLayout);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.GridLayout, 'numeric');
            app.yEditFieldLabel = uilabel(app.GridLayout);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.GridLayout, 'numeric');
            app.zEditFieldLabel = uilabel(app.GridLayout);
            app.zEditFieldLabel.Text = 'z';
            app.zEditField = uieditfield(app.GridLayout, 'numeric');
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.CancelButton = uibutton(app.GridLayout2, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.OkayButton = uibutton(app.GridLayout2, 'push');
            app.OkayButton.ButtonPushedFcn = createCallbackFcn(app, @OkayButtonPushed, true);
            app.OkayButton.Text = 'Okay';
            app.SpeciesEditFieldLabel = uilabel(app.GridLayout);
            app.SpeciesEditFieldLabel.Text = 'Species';
            app.SpeciesEditField = uieditfield(app.GridLayout, 'text');
            app.CellTissueEditFieldLabel = uilabel(app.GridLayout);
            app.CellTissueEditFieldLabel.Text = 'Cell/Tissue';
            app.CellTissueEditField = uieditfield(app.GridLayout, 'text');
            % Show the figure after all components are created
            app.AdditionalMetadataUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = D_ProjectMetadata(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AdditionalMetadataUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AdditionalMetadataUIFigure)
        end
    end
end