classdef D_RVImageSelect < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        GridLayout    matlab.ui.container.GridLayout
        ListBox       matlab.ui.control.ListBox
        Panel         matlab.ui.container.Panel
        GridLayout2   matlab.ui.container.GridLayout
        CancelButton  matlab.ui.control.Button
        OkayButton    matlab.ui.control.Button
    end

    
    properties (Access = private)
        exitBool = false; % Description
        parentApp; % Description
    end
    
    methods (Access = public)
        
        function val = getExitBool(app)
            val = app.exitBool;
        end
        
        function app = loadImageList(app, imageNameList)
            app.ListBox.Items = imageNameList;
        end
        
        function val = getSelectedImageName(app)
            val = app.ListBox.Value;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, imageNameList)
            app.parentApp = mainApp;
            app = app.loadImageList(imageNameList);
        end

        % Button pushed function: OkayButton
        function OkayButtonPushed(app, event)
            app.exitBool = true;
            app.parentApp = app.parentApp.onRVImageSelectReturn(app.exitBool, app.getSelectedImageName());
            delete(app);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.exitBool = false;
            app.parentApp = app.parentApp.onRVImageSelectReturn(app.exitBool, app.getSelectedImageName());
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.OkayButton = uibutton(app.GridLayout2, 'push');
            app.OkayButton.ButtonPushedFcn = createCallbackFcn(app, @OkayButtonPushed, true);
            app.OkayButton.Text = 'Okay';
            app.CancelButton = uibutton(app.GridLayout2, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.ListBox = uilistbox(app.GridLayout);
            app.ListBox.Value = 'Item 3';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = D_RVImageSelect(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end