classdef D_ThreshTune < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ThresholdingParametersUIFigure  matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        Panel                           matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        OkayButton                      matlab.ui.control.Button
        CancelButton                    matlab.ui.control.Button
        CustomPanel                     matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        cbLogOnly                       matlab.ui.control.CheckBox
        cbUseDiff                       matlab.ui.control.CheckBox
        cbUseRaw                        matlab.ui.control.CheckBox
        cbLogFit                        matlab.ui.control.CheckBox
        txtStdevs                       matlab.ui.control.NumericEditField
        ForceStdevsEditFieldLabel       matlab.ui.control.Label
        AdditionalOptionsLabel          matlab.ui.control.Label
        txtWeightI                      matlab.ui.control.NumericEditField
        FitRightIntersectEditFieldLabel  matlab.ui.control.Label
        txtWeightF                      matlab.ui.control.NumericEditField
        FitCornerEditFieldLabel         matlab.ui.control.Label
        txtWeightM                      matlab.ui.control.NumericEditField
        MedianMADEditFieldLabel         matlab.ui.control.Label
        MethodWeightingLabel            matlab.ui.control.Label
        txtMadMax                       matlab.ui.control.NumericEditField
        MaxEditField_2Label             matlab.ui.control.Label
        txtMadMin                       matlab.ui.control.NumericEditField
        MinEditField_2Label             matlab.ui.control.Label
        MADFactorRangeLabel             matlab.ui.control.Label
        WindowSizeLabel                 matlab.ui.control.Label
        txtWinIncr                      matlab.ui.control.NumericEditField
        IncrementEditFieldLabel         matlab.ui.control.Label
        txtWinMax                       matlab.ui.control.NumericEditField
        MaxEditFieldLabel               matlab.ui.control.Label
        txtWinMin                       matlab.ui.control.NumericEditField
        MinEditFieldLabel               matlab.ui.control.Label
        ManualPanel                     matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        txtThRangeMax                   matlab.ui.control.NumericEditField
        QuantRangeMaxEditFieldLabel     matlab.ui.control.Label
        txtThresh                       matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel         matlab.ui.control.Label
        txtThRangeMin                   matlab.ui.control.NumericEditField
        QuantRangeMinEditFieldLabel     matlab.ui.control.Label
        PresetPanel                     matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        PresetIndexSlider               matlab.ui.control.Slider
        PresetIndexSliderLabel          matlab.ui.control.Label
        ButtonGroup                     matlab.ui.container.ButtonGroup
        CustomButton                    matlab.ui.control.RadioButton
        ManualButton                    matlab.ui.control.RadioButton
        PresetButton                    matlab.ui.control.RadioButton
    end

    
    properties (Access = public)
        exitSelection = false; % Description
    end
    
    properties (Access = private)
        currentRB = 0; % 0 = Preset, 1 = Manual, 2 = custom
    end
    
    methods (Access = private)

        function setPresetPanelEnabled(app, bool)
            app.PresetIndexSlider.Enable = bool;
        end

        function setManualPanelEnabled(app, bool)
            app.txtThresh.Enable = bool;
            app.txtThresh.Editable = bool;
            app.txtThRangeMin.Enable = bool;
            app.txtThRangeMin.Editable = bool;
            app.txtThRangeMax.Enable = bool;
            app.txtThRangeMax.Editable = bool;
            app.ThresholdEditFieldLabel.Enable = bool;
            app.QuantRangeMinEditFieldLabel.Enable = bool;
            app.QuantRangeMaxEditFieldLabel.Enable = bool;
        end

        function setCustomPanelEnabled(app, bool)
            app.MinEditFieldLabel.Enable = bool;
            app.txtWinMin.Enable = bool;
            app.txtWinMin.Editable = bool;
            app.MaxEditFieldLabel.Enable = bool;
            app.txtWinMax.Enable = bool;
            app.txtWinMax.Editable = bool;
            app.IncrementEditFieldLabel.Enable = bool;
            app.txtWinIncr.Enable = bool;
            app.txtWinIncr.Editable = bool;
            app.MinEditField_2Label.Enable = bool;
            app.txtMadMin.Enable = bool;
            app.txtMadMin.Editable = bool;
            app.MaxEditField_2Label.Enable = bool;
            app.txtMadMax.Enable = bool;
            app.txtMadMax.Editable = bool;
            app.MedianMADEditFieldLabel.Enable = bool;
            app.txtWeightM.Enable = bool;
            app.txtWeightM.Editable = bool;
            app.FitCornerEditFieldLabel.Enable = bool;
            app.txtWeightF.Enable = bool;
            app.txtWeightF.Editable = bool;
            app.FitRightIntersectEditFieldLabel.Enable = bool;
            app.txtWeightI.Enable = bool;
            app.txtWeightI.Editable = bool;
            app.ForceStdevsEditFieldLabel.Enable = bool;
            app.txtStdevs.Enable = bool;
            app.txtStdevs.Editable = bool;
            app.cbLogFit.Enable = bool;
            app.cbUseRaw.Enable = bool;
            app.cbUseDiff.Enable = bool;
            app.cbLogOnly.Enable = bool;
        end
        
    end
    
    methods (Access = public)
        
        function loadSettingsIntoForm(app, channelInfo)
            if isempty(proj); return; end
            app.currentRB = 2;
            winSizes = channelInfo.thresholdSettings.thParams.window_sizes;
            app.txtWinMin.Value = min(winSizes, [], 'all', 'omitnan');
            app.txtWinMax.Value = max(winSizes, [], 'all', 'omitnan');
            winSizes = diff(winSizes);
            app.txtWinIncr.Value = min(winSizes, [], 'all', 'omitnan');

            app.txtMadMin.Value = channelInfo.thresholdSettings.thParams.mad_factor_min;
            app.txtMadMax.Value = channelInfo.thresholdSettings.thParams.mad_factor_max;
            app.txtWeightM.Value = channelInfo.thresholdSettings.thParams.madth_weight;
            app.txtWeightF.Value = channelInfo.thresholdSettings.thParams.fit_weight;
            app.txtWeightI.Value = channelInfo.thresholdSettings.thParams.fit_ri_weight;
            app.txtStdevs.Value = channelInfo.thresholdSettings.thParams.std_factor;
            app.cbUseRaw.Value = channelInfo.thresholdSettings.thParams.test_data;
            app.cbUseDiff.Value = channelInfo.thresholdSettings.thParams.test_diff;
            if channelInfo.thresholdSettings.thParams.log_proj_mode == 0
                app.cbLogFit.Value = false;
                app.cbLogOnly.Value = false;
            elseif channelInfo.thresholdSettings.thParams.log_proj_mode == 1
                app.cbLogFit.Value = true;
                app.cbLogOnly.Value = true;
            elseif channelInfo.thresholdSettings.thParams.log_proj_mode == 2
                app.cbLogFit.Value = true;
                app.cbLogOnly.Value = false;
            end

            if channelInfo.thresholdSettings.preset > 0
                app.currentRB = 0;
                app.PresetIndexSlider.Value = channelInfo.thresholdSettings.preset;
            end

            if channelInfo.thresholdSettings.manualTh > 0
                app.currentRB = 1;
                app.txtThresh.Value = channelInfo.thresholdSettings.manualTh;
            elseif channelInfo.thresholdSettings.autoBatchThMid > 0
                app.txtThresh.Value = channelInfo.thresholdSettings.autoBatchThMid;
            end

            if channelInfo.thresholdSettings.manualThRangeMin > 0
                app.txtThRangeMin.Value = channelInfo.thresholdSettings.manualThRangeMin;
            elseif channelInfo.thresholdSettings.autoBatchThLo > 0
                app.txtThRangeMin.Value = channelInfo.thresholdSettings.autoBatchThLo;
            end

            if channelInfo.thresholdSettings.manualThRangeMax > 0
                app.txtThRangeMax.Value = channelInfo.thresholdSettings.manualThRangeMax;
            elseif channelInfo.thresholdSettings.autoBatchThHi > 0
                app.txtThRangeMax.Value = channelInfo.thresholdSettings.autoBatchThHi;
            end

            %TODO Does this trigger the callback?
            if app.currentRB == 0
                app.PresetButton.Value = true;
            elseif app.currentRB == 1
                app.ManualButton.Value = true;
            elseif app.currentRB == 2
                app.CustomButton.Value = true;
            end

        end

         function channelInfo = updateSettingsFromForm(app, channelInfo)
            if app.currentRB == 0
                channelInfo.thresholdSettings.preset = round(app.PresetIndexSlider.Value);
                channelInfo.thresholdSettings.manualTh = 0;
            elseif app.currentRB == 1
                channelInfo.thresholdSettings.manualTh = round(app.txtThresh.Value);
                channelInfo.thresholdSettings.manualThRangeMin = round(app.txtThRangeMin.Value);
                channelInfo.thresholdSettings.manualThRangeMax = round(app.txtThRangeMax.Value);
            elseif app.currentRB == 2
                channelInfo.thresholdSettings.manualTh = 0;
                channelInfo.thresholdSettings.preset = 0;

                channelInfo.thresholdSettings.thParams.mad_factor_min = app.txtMadMin.Value;
                channelInfo.thresholdSettings.thParams.mad_factor_max = app.txtMadMax.Value;
                channelInfo.thresholdSettings.thParams.madth_weight = app.txtWeightM.Value;
                channelInfo.thresholdSettings.thParams.fit_weight = app.txtWeightF.Value;
                channelInfo.thresholdSettings.thParams.fit_ri_weight = app.txtWeightI.Value;
                channelInfo.thresholdSettings.thParams.std_factor = app.txtStdevs.Value;
                channelInfo.thresholdSettings.thParams.test_data = app.cbUseRaw.Value;
                channelInfo.thresholdSettings.thParams.test_diff = app.cbUseDiff.Value;
                
                winMin = app.txtWinMin.Value;
                winMax = app.txtWinMax.Value;
                winIncr = app.txtWinIncr.Value;
                channelInfo.thresholdSettings.thParams.window_sizes = winMin:winIncr:winMax;

                if app.cbLogOnly.Value
                    channelInfo.thresholdSettings.thParams.log_proj_mode = 1;
                else
                    if app.cbLogFit.Value
                        channelInfo.thresholdSettings.thParams.log_proj_mode = 2;
                    else
                        channelInfo.thresholdSettings.thParams.log_proj_mode = 0;
                    end
                end
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, channelInfo)
            app.loadSettingsIntoForm(channelInfo);
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            if strcmp(selectedButton.Tag, 'rbPreset')
                app.currentRB = 0;
                app.setPresetPanelEnabled(true);
                app.setManualPanelEnabled(false);
                app.setCustomPanelEnabled(false);
            elseif strcmp(selectedButton.Tag, 'rbManual')
                app.currentRB = 1;
                app.setPresetPanelEnabled(false);
                app.setManualPanelEnabled(true);
                app.setCustomPanelEnabled(false);
            elseif strcmp(selectedButton.Tag, 'rbCustom')
                app.currentRB = 2;
                app.setPresetPanelEnabled(false);
                app.setManualPanelEnabled(false);
                app.setCustomPanelEnabled(true);
            end
        end

        % Button pushed function: OkayButton
        function OkayButtonPushed(app, event)
            app.exitSelection = true;
            delete(app);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.exitSelection = false;
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ThresholdingParametersUIFigure = uifigure('Visible', 'off');
            app.ThresholdingParametersUIFigure.Name = 'Thresholding Parameters';
            app.GridLayout = uigridlayout(app.ThresholdingParametersUIFigure);
            app.ButtonGroup = uibuttongroup(app.GridLayout);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.PresetButton = uiradiobutton(app.ButtonGroup);
            app.PresetButton.Tag = 'rbPreset';
            app.PresetButton.Text = 'Preset';
            app.PresetButton.Value = true;
            app.ManualButton = uiradiobutton(app.ButtonGroup);
            app.ManualButton.Tag = 'rbManual';
            app.ManualButton.Text = 'Manual';
            app.CustomButton = uiradiobutton(app.ButtonGroup);
            app.CustomButton.Tag = 'rbCustom';
            app.CustomButton.Text = 'Custom';
            app.PresetPanel = uipanel(app.GridLayout);
            app.PresetPanel.Title = 'Preset';
            app.GridLayout4 = uigridlayout(app.PresetPanel);
            app.PresetIndexSliderLabel = uilabel(app.GridLayout4);
            app.PresetIndexSliderLabel.Text = 'Preset Index';
            app.PresetIndexSlider = uislider(app.GridLayout4);
            app.PresetIndexSlider.Limits = [1 11];
            app.PresetIndexSlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10 11];
            app.PresetIndexSlider.MajorTickLabels = {'Precise', '', '', '', '', 'Default', '', '', '', '', 'Sensitive'};
            app.PresetIndexSlider.MinorTicks = [1 2 3 4 5 6 7 8 9 10 11];
            app.PresetIndexSlider.Value = 6;
            app.ManualPanel = uipanel(app.GridLayout);
            app.ManualPanel.Title = 'Manual';
            app.GridLayout2 = uigridlayout(app.ManualPanel);
            app.QuantRangeMinEditFieldLabel = uilabel(app.GridLayout2);
            app.QuantRangeMinEditFieldLabel.Enable = 'off';
            app.QuantRangeMinEditFieldLabel.Text = 'Quant Range (Min)';
            app.txtThRangeMin = uieditfield(app.GridLayout2, 'numeric');
            app.txtThRangeMin.Editable = 'off';
            app.txtThRangeMin.Enable = 'off';
            app.ThresholdEditFieldLabel = uilabel(app.GridLayout2);
            app.ThresholdEditFieldLabel.Enable = 'off';
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.txtThresh = uieditfield(app.GridLayout2, 'numeric');
            app.txtThresh.Editable = 'off';
            app.txtThresh.Enable = 'off';
            app.QuantRangeMaxEditFieldLabel = uilabel(app.GridLayout2);
            app.QuantRangeMaxEditFieldLabel.Enable = 'off';
            app.QuantRangeMaxEditFieldLabel.Text = 'Quant Range (Max)';
            app.txtThRangeMax = uieditfield(app.GridLayout2, 'numeric');
            app.txtThRangeMax.Editable = 'off';
            app.txtThRangeMax.Enable = 'off';
            app.CustomPanel = uipanel(app.GridLayout);
            app.CustomPanel.Title = 'Custom';
            app.GridLayout3 = uigridlayout(app.CustomPanel);
            app.MinEditFieldLabel = uilabel(app.GridLayout3);
            app.MinEditFieldLabel.Enable = 'off';
            app.MinEditFieldLabel.Text = 'Min';
            app.txtWinMin = uieditfield(app.GridLayout3, 'numeric');
            app.txtWinMin.Limits = [0 Inf];
            app.txtWinMin.Editable = 'off';
            app.txtWinMin.Enable = 'off';
            app.txtWinMin.Value = 0.006;
            app.MaxEditFieldLabel = uilabel(app.GridLayout3);
            app.MaxEditFieldLabel.Enable = 'off';
            app.MaxEditFieldLabel.Text = 'Max';
            app.txtWinMax = uieditfield(app.GridLayout3, 'numeric');
            app.txtWinMax.Limits = [0 Inf];
            app.txtWinMax.Editable = 'off';
            app.txtWinMax.Enable = 'off';
            app.txtWinMax.Value = 0.042;
            app.IncrementEditFieldLabel = uilabel(app.GridLayout3);
            app.IncrementEditFieldLabel.Enable = 'off';
            app.IncrementEditFieldLabel.Text = 'Increment';
            app.txtWinIncr = uieditfield(app.GridLayout3, 'numeric');
            app.txtWinIncr.Limits = [0 Inf];
            app.txtWinIncr.Editable = 'off';
            app.txtWinIncr.Enable = 'off';
            app.txtWinIncr.Value = 0.006;
            app.WindowSizeLabel = uilabel(app.GridLayout3);
            app.WindowSizeLabel.Text = 'Window Size';
            app.MADFactorRangeLabel = uilabel(app.GridLayout3);
            app.MADFactorRangeLabel.Text = 'MAD Factor Range';
            app.MinEditField_2Label = uilabel(app.GridLayout3);
            app.MinEditField_2Label.Enable = 'off';
            app.MinEditField_2Label.Text = 'Min';
            app.txtMadMin = uieditfield(app.GridLayout3, 'numeric');
            app.txtMadMin.Editable = 'off';
            app.txtMadMin.Enable = 'off';
            app.txtMadMin.Value = -1;
            app.MaxEditField_2Label = uilabel(app.GridLayout3);
            app.MaxEditField_2Label.Enable = 'off';
            app.MaxEditField_2Label.Text = 'Max';
            app.txtMadMax = uieditfield(app.GridLayout3, 'numeric');
            app.txtMadMax.Editable = 'off';
            app.txtMadMax.Enable = 'off';
            app.txtMadMax.Value = 1;
            app.MethodWeightingLabel = uilabel(app.GridLayout3);
            app.MethodWeightingLabel.Text = 'Method Weighting';
            app.MedianMADEditFieldLabel = uilabel(app.GridLayout3);
            app.MedianMADEditFieldLabel.Enable = 'off';
            app.MedianMADEditFieldLabel.Text = 'Median/MAD';
            app.txtWeightM = uieditfield(app.GridLayout3, 'numeric');
            app.txtWeightM.Limits = [0 1];
            app.txtWeightM.Editable = 'off';
            app.txtWeightM.Enable = 'off';
            app.txtWeightM.Value = 0.25;
            app.FitCornerEditFieldLabel = uilabel(app.GridLayout3);
            app.FitCornerEditFieldLabel.Enable = 'off';
            app.FitCornerEditFieldLabel.Text = 'Fit Corner';
            app.txtWeightF = uieditfield(app.GridLayout3, 'numeric');
            app.txtWeightF.Limits = [0 1];
            app.txtWeightF.Editable = 'off';
            app.txtWeightF.Enable = 'off';
            app.FitRightIntersectEditFieldLabel = uilabel(app.GridLayout3);
            app.FitRightIntersectEditFieldLabel.Enable = 'off';
            app.FitRightIntersectEditFieldLabel.Text = 'Fit Right Intersect';
            app.txtWeightI = uieditfield(app.GridLayout3, 'numeric');
            app.txtWeightI.Limits = [0 1];
            app.txtWeightI.Editable = 'off';
            app.txtWeightI.Enable = 'off';
            app.txtWeightI.Value = 0.75;
            app.AdditionalOptionsLabel = uilabel(app.GridLayout3);
            app.AdditionalOptionsLabel.Text = 'Additional Options';
            app.ForceStdevsEditFieldLabel = uilabel(app.GridLayout3);
            app.ForceStdevsEditFieldLabel.Enable = 'off';
            app.ForceStdevsEditFieldLabel.Text = 'Force Stdevs';
            app.txtStdevs = uieditfield(app.GridLayout3, 'numeric');
            app.txtStdevs.Editable = 'off';
            app.txtStdevs.Enable = 'off';
            app.cbLogFit = uicheckbox(app.GridLayout3);
            app.cbLogFit.Enable = 'off';
            app.cbLogFit.Text = 'Fit to log projection';
            app.cbLogFit.Value = true;
            app.cbUseRaw = uicheckbox(app.GridLayout3);
            app.cbUseRaw.Enable = 'off';
            app.cbUseRaw.Text = 'Include initial count curve';
            app.cbUseDiff = uicheckbox(app.GridLayout3);
            app.cbUseDiff.Enable = 'off';
            app.cbUseDiff.Text = 'Include diff curve';
            app.cbLogOnly = uicheckbox(app.GridLayout3);
            app.cbLogOnly.Enable = 'off';
            app.cbLogOnly.Text = 'Use only log projection';
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout5 = uigridlayout(app.Panel);
            app.CancelButton = uibutton(app.GridLayout5, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.OkayButton = uibutton(app.GridLayout5, 'push');
            app.OkayButton.ButtonPushedFcn = createCallbackFcn(app, @OkayButtonPushed, true);
            app.OkayButton.Text = 'Okay';
            % Show the figure after all components are created
            app.ThresholdingParametersUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = D_ThreshTune(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ThresholdingParametersUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ThresholdingParametersUIFigure)
        end
    end
end