classdef TrueSpotResultViewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Toolbar                    matlab.ui.container.Toolbar
        btnLoad                    matlab.ui.container.toolbar.PushTool
        btnImageSelect             matlab.ui.container.toolbar.PushTool
        GridLayout                 matlab.ui.container.GridLayout
        ChannelsPanel              matlab.ui.container.Panel
        GridLayout2                matlab.ui.container.GridLayout
        RenderPanel                matlab.ui.container.Panel
        GridLayout4                matlab.ui.container.GridLayout
        tgMaxProj                  matlab.ui.control.Switch
        MaxZProjectionSwitchLabel  matlab.ui.control.Label
        sldZSlice                  matlab.ui.control.Slider
        ZSliceSliderLabel          matlab.ui.control.Label
        iRenderPanel               matlab.ui.control.UIAxes
        LayersPanel                matlab.ui.container.Panel
        GridLayout3                matlab.ui.container.GridLayout
    end

    
    properties (Access = private)
        lastLoadDir = '.'; %For load batch dialog box

        dialogImageSelect; % Image select dialog

        loadedBatch; % rvbstruct
        imageDict; %Lookup of images (rvistruct) by name (for selector)

        currentImageInfo; %rvistruct
        loadedStackData; %Stored here as uint8 regardless of source depth (Y,X,Z,C)

        channelViewComp = true; %Bool - channel composite mode
        channelRenderInfo = []; %ResViewer Channel Render structs
    end
    
    methods (Access = public)
        
        function app = setWait(app)
            %TODO
            %https://stackoverflow.com/questions/57868712/how-to-implement-a-modal-pop-up-window-on-an-app-mlapp
            set(app.UIFigure.Children, 'Enable', 'off');
        end

        function app = unsetWait(app)
            %TODO
            set(app.UIFigure.Children, 'Enable', 'on');
        end
        
        function app = onRVImageSelectReturn(app, exitBool, imageName)
            app = app.unsetWait();
            if exitBool
                app = app.loadAndUpdateToImage(imageName);
            end
        end
    end
    
    methods (Access = private)
        
        function inamelist = getCurrentBatchImageList(app)
            %TODO
        end
        
        function app = loadSavedState(app, rvState)
            %TODO
            app.lastLoadDir = rvState.lastLoadDir;
        end
        
        function rvState = packageSavedState(app)
            %TODO
            rvState = struct();
            rvState.lastLoadDir = app.lastLoadDir;
        end

        function app = renderImageView(app)
            %TODO

            %Base image...

            %Masks...

        end

        function app = loadAndUpdateToImage(app, imageName)
            %TODO
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: btnImageSelect
        function btnImageSelectClicked(app, event)
            %https://stackoverflow.com/questions/57868712/how-to-implement-a-modal-pop-up-window-on-an-app-mlapp
            app = app.setWait();
            app.dialogImageSelect = D_RVImageSelect(app, app.getCurrentBatchImageList());
        end

        % Callback function: btnLoad
        function btnLoadClicked(app, event)
            selpath = uigetdir(app.lastLoadDir, 'Select a batch directory');
            if isempty(selpath); return; end

            %Check for tsrvbatch.mat file
            rvbFilePath = [selpath filesep 'tsrvbatch.mat'];
            if isfile(rvbFilePath)
                [app.loadedBatch, rvState] = ResViewerFunctions.loadResViewerBatch(rvbFilePath);
                app = app.loadSavedState(rvState);
                clear rvState
            else
                guimsg = ['Existing batch info file was not found.', 'Scanning selected directory for new batch...'];
                uialert(app, guimsg, 'Import Batch', 'Icon', 'warning');
                app.loadedBatch = ResViewerFunctions.importBatchDir(selpath);

                %Check image paths to see if need to be updated
                app.currentImageInfo = app.loadedBatch.imageInfo{1};
                if ~isfile(app.currentImageInfo.imagePath)
                    guimsg = ['Path(s) to source images in run batch are not valid.', 'Please select local directory containing original image stack files.'];
                    uialert(app, guimsg, 'Select Image File Directory', 'Icon', 'warning');
                    idirpath = uigetdir(app.lastLoadDir, 'Select an image directory');
                    app.loadedBatch = ResViewerFunctions.updateImagePaths(app.loadedBatch, idirpath);
                    clear idirpath
                end
                clear guimsg

                rvState = app.packageSavedState();
                ResViewerFunctions.saveResViewerBatch(app.loadedBatch, rvState, rvbFilePath);
            end

            %TODO UPDATE
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.btnLoad = uipushtool(app.Toolbar);
            app.btnLoad.Tooltip = {'Load'};
            app.btnLoad.ClickedCallback = createCallbackFcn(app, @btnLoadClicked, true);
            app.btnImageSelect = uipushtool(app.Toolbar);
            app.btnImageSelect.Tooltip = {'Select Image'};
            app.btnImageSelect.ClickedCallback = createCallbackFcn(app, @btnImageSelectClicked, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LayersPanel = uipanel(app.GridLayout);
            app.LayersPanel.Title = 'Layers';
            app.GridLayout3 = uigridlayout(app.LayersPanel);
            app.RenderPanel = uipanel(app.GridLayout);
            app.RenderPanel.Title = 'Render';
            app.GridLayout4 = uigridlayout(app.RenderPanel);
            app.iRenderPanel = uiaxes(app.GridLayout4);
            app.iRenderPanel.XTick = [];
            app.iRenderPanel.YTick = [];
            app.ZSliceSliderLabel = uilabel(app.GridLayout4);
            app.ZSliceSliderLabel.Text = 'Z Slice';
            app.sldZSlice = uislider(app.GridLayout4);
            app.MaxZProjectionSwitchLabel = uilabel(app.GridLayout4);
            app.MaxZProjectionSwitchLabel.Text = 'Max Z Projection';
            app.tgMaxProj = uiswitch(app.GridLayout4, 'slider');
            app.ChannelsPanel = uipanel(app.GridLayout);
            app.ChannelsPanel.Title = 'Channels';
            app.GridLayout2 = uigridlayout(app.ChannelsPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TrueSpotResultViewer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end