classdef batchInfoPanel < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout                     matlab.ui.container.GridLayout
        Panel_3                        matlab.ui.container.Panel
        GridLayout4                    matlab.ui.container.GridLayout
        btnBrowseCtrl                  matlab.ui.control.Button
        Panel_2                        matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        btnBrowseOut                   matlab.ui.control.Button
        Panel                          matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        btnBrowseInputDir              matlab.ui.control.Button
        btnBrowseInput                 matlab.ui.control.Button
        ControlPathEditField           matlab.ui.control.EditField
        ControlPathEditFieldLabel      matlab.ui.control.Label
        OutputDirectoryEditField       matlab.ui.control.EditField
        OutputDirectoryEditFieldLabel  matlab.ui.control.Label
        InputPathEditField             matlab.ui.control.EditField
        InputPathEditFieldLabel        matlab.ui.control.Label
        ProjectNameEditField           matlab.ui.control.EditField
        ProjectNameEditFieldLabel      matlab.ui.control.Label
    end

    properties (Access = public)
        controlPathDefo (1,1) string = ""; % Default path for control specifications
        inputPathDefo (1,1) string = ""; % Default path for browse input dialog
        outputPathDefo (1,1) string = ""; % Default path for browse output
    end
    
    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: btnBrowseInput
        function btnBrowseInputButtonPushed(comp, event)
            [file,location] = uigetfile(comp.inputPathDefo,...
                        'Select Input Specification Table');
            if ~isequal(file,0)
                mypath = fullfile(location,file);
                comp.InputPathEditField.Value = mypath;
                comp.inputPathDefo = mypath;
            end
        end

        % Button pushed function: btnBrowseInputDir
        function btnBrowseInputDirButtonPushed(comp, event)
            dirPath = uigetdir(comp.inputPathDefo, 'Select Input Directory');
            if ~isempty(dirPath)
                comp.InputPathEditField.Value = dirPath;
                comp.inputPathDefo = dirPath;
            end
        end

        % Button pushed function: btnBrowseOut
        function btnBrowseOutButtonPushed(comp, event)
            dirPath = uigetdir(comp.outputPathDefo, 'Select Output Directory');
            if ~isempty(dirPath)
                comp.OutputDirectoryEditField.Value = dirPath;
                comp.outputPathDefo = dirPath;
            end
        end

        % Button pushed function: btnBrowseCtrl
        function btnBrowseCtrlButtonPushed(comp, event)
            [file,location] = uigetfile(comp.controlPathDefo,...
                        'Select Control Specification Table');
            if ~isequal(file,0)
                mypath = fullfile(location,file);
                comp.ControlPathEditField.Value = mypath;
                comp.controlPathDefo = mypath;
            end
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 452 287];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.GridLayout = uigridlayout(comp);
            comp.ProjectNameEditFieldLabel = uilabel(comp.GridLayout);
            comp.ProjectNameEditFieldLabel.Text = 'Project Name';
            comp.ProjectNameEditField = uieditfield(comp.GridLayout, 'text');
            comp.InputPathEditFieldLabel = uilabel(comp.GridLayout);
            comp.InputPathEditFieldLabel.Text = 'Input Path';
            comp.InputPathEditField = uieditfield(comp.GridLayout, 'text');
            comp.OutputDirectoryEditFieldLabel = uilabel(comp.GridLayout);
            comp.OutputDirectoryEditFieldLabel.Text = 'Output Directory';
            comp.OutputDirectoryEditField = uieditfield(comp.GridLayout, 'text');
            comp.ControlPathEditFieldLabel = uilabel(comp.GridLayout);
            comp.ControlPathEditFieldLabel.Text = 'Control Path';
            comp.ControlPathEditField = uieditfield(comp.GridLayout, 'text');
            comp.Panel = uipanel(comp.GridLayout);
            comp.GridLayout2 = uigridlayout(comp.Panel);
            comp.btnBrowseInput = uibutton(comp.GridLayout2, 'push');
            comp.btnBrowseInput.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @btnBrowseInputButtonPushed, true);
            comp.btnBrowseInput.Text = 'Browse Files...';
            comp.btnBrowseInputDir = uibutton(comp.GridLayout2, 'push');
            comp.btnBrowseInputDir.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @btnBrowseInputDirButtonPushed, true);
            comp.btnBrowseInputDir.Text = 'Browse Directories...';
            comp.Panel_2 = uipanel(comp.GridLayout);
            comp.GridLayout3 = uigridlayout(comp.Panel_2);
            comp.btnBrowseOut = uibutton(comp.GridLayout3, 'push');
            comp.btnBrowseOut.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @btnBrowseOutButtonPushed, true);
            comp.btnBrowseOut.Text = 'Browse...';
            comp.Panel_3 = uipanel(comp.GridLayout);
            comp.GridLayout4 = uigridlayout(comp.Panel_3);
            comp.btnBrowseCtrl = uibutton(comp.GridLayout4, 'push');
            comp.btnBrowseCtrl.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @btnBrowseCtrlButtonPushed, true);
            comp.btnBrowseCtrl.Text = 'Browse...';
        end
    end
end