classdef spotsViewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        Panel                      matlab.ui.container.Panel
        GridLayout2                matlab.ui.container.GridLayout
        PnlSliders                 matlab.ui.container.Panel
        GridLayout5                matlab.ui.container.GridLayout
        LblThresh                  matlab.ui.control.Label
        LblZSlice                  matlab.ui.control.Label
        ThresholdSlider            matlab.ui.control.Slider
        ThresholdSliderLabel       matlab.ui.control.Label
        ZSliceSlider               matlab.ui.control.Slider
        ZSliceSliderLabel          matlab.ui.control.Label
        PnlInfo                    matlab.ui.container.Panel
        GridLayout4                matlab.ui.container.GridLayout
        Position00Label            matlab.ui.control.Label
        SpotCalls0Label            matlab.ui.control.Label
        PnlToggles                 matlab.ui.container.Panel
        GridLayout3                matlab.ui.container.GridLayout
        GlobalContrastSwitchLabel  matlab.ui.control.Label
        SpotCallsSwitch            matlab.ui.control.Switch
        SpotCallsSwitchLabel       matlab.ui.control.Label
        NucMaskSwitch              matlab.ui.control.Switch
        NucMaskSwitchLabel         matlab.ui.control.Label
        GlobalContrastSwitch       matlab.ui.control.Switch
        CellMaskSwitch             matlab.ui.control.Switch
        CellMaskSwitchLabel        matlab.ui.control.Label
        MaxProjectionSwitch        matlab.ui.control.Switch
        MaxProjectionSwitchLabel   matlab.ui.control.Label
        LoGFilterSwitch            matlab.ui.control.Switch
        LoGFilterSwitchLabel       matlab.ui.control.Label
        ImageFig                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        visualizerData %VisInteractive instance
    end
    
    methods (Access = public)
        
        function app = setVisObject(app, visObj)
            app.visualizerData = visObj;
            app.updateToMatchVis();
        end
    end
    
    methods (Access = private)
        
        function updateToMatchVis(app)
            if ~isempty(app.visualizerData)
                %Toggles
                app.LoGFilterSwitch.Value = app.visualizerData.useFilt;
                app.MaxProjectionSwitch.Value = app.visualizerData.maxProj;
                app.GlobalContrastSwitch.Value = app.visualizerData.globalContrast;
                app.CellMaskSwitch.Value = app.visualizerData.cellLayerOn;
                app.NucMaskSwitch.Value = app.visualizerData.nucLayerOn;
                app.SpotCallsSwitch.Value = app.visualizerData.spotCircleLayerOn;

                %Sliders
                [th_min, th_max, th_incr] = app.visualizerData.getThresholdInfo();
                th_now = app.visualizerData.currentThresh;
                Z = app.visualizerData.getZSliceCount();
                z_now = app.visualizerData.currentSlice;
                app.ThresholdSlider.Limits = [th_min th_max];
                app.ThresholdSlider.Step = th_incr;
                app.ThresholdSlider.Value = th_now;
                app.updateThreshValLabel();
                app.ZSliceSlider.Limits = [1 Z];
                app.ZSliceSlider.Value = z_now;
                app.updateZValLabel();
                app.ZSliceSlider.Enable = ~app.visualizerData.maxProj;
                
                %Counts, position
                app.updateSpotCallCountLabel();
                app.updateViewCoordsLabel();

                %Image render
                app.rerenderImage();
            else
                app.clearVis();
            end
        end
        
        function clearVis(app)
            %TODO
        end

        function rerenderImage(app)
            %TODO
            axes(app.ImageFig);
            cla;
            if ~isempty(app.visualizerData)
                app.visualizerData = app.visualizerData.updateRenderToCurrent();
            end
        end

        function updateSpotCallCountLabel(app)
            if ~isempty(app.visualizerData)
                app.SpotCalls0Label.Text = ['Spot Calls: ' num2str(app.visualizerData.getCurrentCallCount())];
            else
                app.SpotCalls0Label.Text = 'Spot Calls: 0';
            end
        end

        function updateViewCoordsLabel(app)
            if ~isempty(app.visualizerData)
                [x0, x1, y0, y1] = app.visualizerData.getViewWindow();
                app.Position00Label.Text = ['View: (' num2str(x0) ',' num2str(y0) ')' ...
                    '(' num2str(x1) ',' num2str(y1) ')'];
            else
                app.SpotCalls0Label.Text = 'View: [None]';
            end
        end

        function updateThreshValLabel(app)
            val = app.ThresholdSlider.Value;
            app.LblThresh.Text = ['Current Value: ' num2str(val)];
        end

        function updateZValLabel(app)
            val = app.ZSliceSlider.Value;
            app.LblZSlice.Text = ['Current Value: ' num2str(val)];
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: LoGFilterSwitch
        function LoGFilterSwitchValueChanged(app, event)
            value = app.LoGFilterSwitch.Value;
            if ~isempty(app.visualizerData)
                app.visualizerData.useFilt = value;
                app.rerenderImage();
            end
        end

        % Value changed function: ZSliceSlider
        function ZSliceSliderValueChanged(app, event)
            value = app.ZSliceSlider.Value;
            if ~isempty(app.visualizerData)
                app.visualizerData.currentSlice = value;
                app.rerenderImage();
            end
            app.updateZValLabel();
        end

        % Value changed function: ThresholdSlider
        function ThresholdSliderValueChanged(app, event)
            value = app.ThresholdSlider.Value;
            if ~isempty(app.visualizerData)
                app.visualizerData.currentThresh = value;
                app.rerenderImage();
            end
            app.updateThreshValLabel();
            app.updateSpotCallCountLabel();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Panel = uipanel(app.GridLayout);
            app.Panel.Title = 'Panel';
            app.GridLayout2 = uigridlayout(app.Panel);
            app.ImageFig = uiaxes(app.GridLayout2);
            app.ImageFig.XTickLabel = '';
            app.ImageFig.YTickLabel = '';
            app.PnlToggles = uipanel(app.GridLayout2);
            app.GridLayout3 = uigridlayout(app.PnlToggles);
            app.LoGFilterSwitchLabel = uilabel(app.GridLayout3);
            app.LoGFilterSwitchLabel.Text = 'LoG Filter';
            app.LoGFilterSwitch = uiswitch(app.GridLayout3, 'slider');
            app.LoGFilterSwitch.ValueChangedFcn = createCallbackFcn(app, @LoGFilterSwitchValueChanged, true);
            app.MaxProjectionSwitchLabel = uilabel(app.GridLayout3);
            app.MaxProjectionSwitchLabel.Text = 'Max Projection';
            app.MaxProjectionSwitch = uiswitch(app.GridLayout3, 'slider');
            app.CellMaskSwitchLabel = uilabel(app.GridLayout3);
            app.CellMaskSwitchLabel.Text = 'Cell Mask';
            app.CellMaskSwitch = uiswitch(app.GridLayout3, 'slider');
            app.GlobalContrastSwitch = uiswitch(app.GridLayout3, 'slider');
            app.NucMaskSwitchLabel = uilabel(app.GridLayout3);
            app.NucMaskSwitchLabel.Text = 'Nuc. Mask';
            app.NucMaskSwitch = uiswitch(app.GridLayout3, 'slider');
            app.SpotCallsSwitchLabel = uilabel(app.GridLayout3);
            app.SpotCallsSwitchLabel.Text = 'Spot Calls';
            app.SpotCallsSwitch = uiswitch(app.GridLayout3, 'slider');
            app.GlobalContrastSwitchLabel = uilabel(app.GridLayout3);
            app.GlobalContrastSwitchLabel.Text = 'Global Contrast';
            app.PnlInfo = uipanel(app.GridLayout2);
            app.GridLayout4 = uigridlayout(app.PnlInfo);
            app.SpotCalls0Label = uilabel(app.GridLayout4);
            app.SpotCalls0Label.Text = 'Spot Calls: 0';
            app.Position00Label = uilabel(app.GridLayout4);
            app.Position00Label.Text = 'Position: 0,0';
            app.PnlSliders = uipanel(app.GridLayout2);
            app.GridLayout5 = uigridlayout(app.PnlSliders);
            app.ZSliceSliderLabel = uilabel(app.GridLayout5);
            app.ZSliceSliderLabel.Text = 'Z Slice';
            app.ZSliceSlider = uislider(app.GridLayout5);
            app.ZSliceSlider.Limits = [1 50];
            app.ZSliceSlider.ValueChangedFcn = createCallbackFcn(app, @ZSliceSliderValueChanged, true);
            app.ZSliceSlider.Step = 1;
            app.ZSliceSlider.Value = 1;
            app.ThresholdSliderLabel = uilabel(app.GridLayout5);
            app.ThresholdSliderLabel.Text = 'Threshold';
            app.ThresholdSlider = uislider(app.GridLayout5);
            app.ThresholdSlider.Limits = [10 500];
            app.ThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @ThresholdSliderValueChanged, true);
            app.ThresholdSlider.Step = 1;
            app.ThresholdSlider.Value = 200;
            app.LblZSlice = uilabel(app.GridLayout5);
            app.LblZSlice.Text = 'Current Value: 1';
            app.LblThresh = uilabel(app.GridLayout5);
            app.LblThresh.Text = 'Current Value: 200';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = spotsViewer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end